classdef epileptic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PredictionanddetectionofepilepticseizuressetupPanel  matlab.ui.container.Panel
        LoadPatientDatasetButtonGroup  matlab.ui.container.ButtonGroup
        Patient54802Button             matlab.ui.control.RadioButton
        Patient112502Button            matlab.ui.control.RadioButton
        DatasetBalanceSwitchLabel      matlab.ui.control.Label
        DatasetBalanceSwitch           matlab.ui.control.Switch
        SpecificationButtonGroup       matlab.ui.container.ButtonGroup
        PredictionButton               matlab.ui.control.RadioButton
        DetectionButton                matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        TrainMultilayerNNButton        matlab.ui.control.Button
        TrainNetworkwithDelaysButton   matlab.ui.control.Button
        TrainLSTMnetworkButton         matlab.ui.control.Button
        TrainCNNnetworkButton          matlab.ui.control.Button
        ClassificationButtonGroup      matlab.ui.container.ButtonGroup
        PointButton                    matlab.ui.control.RadioButton
        GroupButton                    matlab.ui.control.RadioButton
        NetworkDetectionPredictionoutputsPanel  matlab.ui.container.Panel
        PreIctalPredictionPanel        matlab.ui.container.Panel
        ResultsPanel                   matlab.ui.container.Panel
        TruePositivesLabel             matlab.ui.control.Label
        TrueNegativesLabel             matlab.ui.control.Label
        FalsePositivesLabel            matlab.ui.control.Label
        FalseNegativesLabel            matlab.ui.control.Label
        PTP                            matlab.ui.control.Label
        PTN                            matlab.ui.control.Label
        PFP                            matlab.ui.control.Label
        PFN                            matlab.ui.control.Label
        SensitivitySpecificityPanel    matlab.ui.container.Panel
        SensitivityLabel               matlab.ui.control.Label
        SpecificityLabel               matlab.ui.control.Label
        PSensitivity                   matlab.ui.control.Label
        PSpecificity                   matlab.ui.control.Label
        IctalDetectionPanel            matlab.ui.container.Panel
        ResultsPanel_2                 matlab.ui.container.Panel
        TruePositivesLabel_3           matlab.ui.control.Label
        TrueNegativesLabel_3           matlab.ui.control.Label
        FalsePositivesLabel_3          matlab.ui.control.Label
        FalseNegativesLabel_3          matlab.ui.control.Label
        DTP                            matlab.ui.control.Label
        DTN                            matlab.ui.control.Label
        DFP                            matlab.ui.control.Label
        DFN                            matlab.ui.control.Label
        SensitivitySpecificityPanel_2  matlab.ui.container.Panel
        SensitivityLabel_2             matlab.ui.control.Label
        SpecificityLabel_2             matlab.ui.control.Label
        DSensitivity                   matlab.ui.control.Label
        DSpecificity                   matlab.ui.control.Label
        TrainasofrmaxlayerwithautoencondersPanel  matlab.ui.container.Panel
        TrainButton                    matlab.ui.control.Button
        FirstencoderfeaturesDropDownLabel  matlab.ui.control.Label
        FirstencoderfeaturesDropDown   matlab.ui.control.DropDown
        SecondencoderfeaturesDropDownLabel  matlab.ui.control.Label
        SecondencoderfeaturesDropDown  matlab.ui.control.DropDown
        SelectinthesetupDatasetBalanceaspreferredLabel  matlab.ui.control.Label
        TestanalreadytrainednetworkPanel  matlab.ui.container.Panel
        ChooseanetworkDropDownLabel    matlab.ui.control.Label
        ChooseanetworkDropDown         matlab.ui.control.DropDown
        AnynetworktrainedinthisapplicationwillLabel  matlab.ui.control.Label
        TestNNButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        patient_dataset = 1; % Patient Dataset chosen
        weight_error = 'prediction'; %Weight error chosen
        balance = 0;
        features1 = 25;
        features2 = 25;
        trained_network = 'multilayer';
        saved_net;
        group = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: LoadPatientDatasetButtonGroup
        function LoadPatientDatasetButtonGroupSelectionChanged(app, event)
            selectedButton = app.LoadPatientDatasetButtonGroup.SelectedObject;
            patient1 = app.Patient54802Button.Value;
            patient2 = app.Patient112502Button.Value;
            no_error = app.NoneButton.Value;
            detection_error = app.DetectionButton.Value;
            prediction_error = app.PredictionButton.Value;
            if patient1 == 1
                app.patient_dataset = 1;
            elseif patient2 == 1
                app.patient_dataset = 2;
            end
            
            if no_error == 1
                app.weight_error = 'none';
            elseif detection_error == 1
                app.weight_error = 'detection';
            elseif prediction_error == 1
                app.weight_error = 'prediction';
            end
            
        end

        % Button pushed function: TrainMultilayerNNButton
        function TrainMultilayerNNButtonPushed(app, event)
            [P_train, T_train, P_test, T_test] = loadDataset(app.patient_dataset, app.balance);
            net = train_NN('multilayer', P_train, T_train, app.weight_error);
            [~, pre_ictal, ictal] = test_NN('multilayer', net, P_test, T_test, app.group);
            app.PTP.Text = num2str(pre_ictal.true_positive);
            app.PTN.Text = num2str(pre_ictal.true_negative);
            app.PFP.Text = num2str(pre_ictal.false_positive);
            app.PFN.Text = num2str(pre_ictal.false_negative);
            
            app.DTP.Text = num2str(ictal.true_positive);
            app.DTN.Text = num2str(ictal.true_negative);
            app.DFP.Text = num2str(ictal.false_positive);
            app.DFN.Text = num2str(ictal.false_negative);
            
            app.PSensitivity.Text = num2str(pre_ictal.true_positive/(pre_ictal.true_positive + pre_ictal.false_negative));
            app.PSpecificity.Text = num2str(pre_ictal.true_negative/(pre_ictal.true_negative + pre_ictal.false_positive));
                
            app.DSensitivity.Text = num2str(ictal.true_positive/(ictal.true_positive + ictal.false_negative));
            app.DSpecificity.Text = num2str(ictal.true_negative/(ictal.true_negative + ictal.false_positive));
            
        end

        % Button pushed function: TrainNetworkwithDelaysButton
        function TrainNetworkwithDelaysButtonPushed(app, event)
            [P_train, T_train, P_test, T_test] = loadDataset(app.patient_dataset, app.balance);
            net = train_NN('Multilayer with Delays', P_train, T_train, app.weight_error);
            [~, pre_ictal, ictal] = test_NN('Multilayer with Delays', net, P_test, T_test, app.group);
            app.PTP.Text = num2str(pre_ictal.true_positive);
            app.PTN.Text = num2str(pre_ictal.true_negative);
            app.PFP.Text = num2str(pre_ictal.false_positive);
            app.PFN.Text = num2str(pre_ictal.false_negative);
            
            app.DTP.Text = num2str(ictal.true_positive);
            app.DTN.Text = num2str(ictal.true_negative);
            app.DFP.Text = num2str(ictal.false_positive);
            app.DFN.Text = num2str(ictal.false_negative);
            
            app.PSensitivity.Text = num2str(pre_ictal.true_positive/(pre_ictal.true_positive + pre_ictal.false_negative));
            app.PSpecificity.Text = num2str(pre_ictal.true_negative/(pre_ictal.true_negative + pre_ictal.false_positive));
                
            app.DSensitivity.Text = num2str(ictal.true_positive/(ictal.true_positive + ictal.false_negative));
            app.DSpecificity.Text = num2str(ictal.true_negative/(ictal.true_negative + ictal.false_positive));
            
        end

        % Value changed function: DatasetBalanceSwitch
        function DatasetBalanceSwitchValueChanged(app, event)
            value = app.DatasetBalanceSwitch.Value;
            if strcmp(value, 'On')
                app.balance = 1;
            end
            if strcmp(value, 'Off')
                app.balance = 0;                
            end
        end

        % Button pushed function: TrainLSTMnetworkButton
        function TrainLSTMnetworkButtonPushed(app, event)
            [P_train, T_train, P_test, T_test] = loadDataset(app.patient_dataset, app.balance);
            net = train_NN('LSTM', P_train, T_train, app.weight_error);
            [~, pre_ictal, ictal] = test_NN('LSTM', net, P_test, T_test, app.group);
            app.PTP.Text = num2str(pre_ictal.true_positive);
            app.PTN.Text = num2str(pre_ictal.true_negative);
            app.PFP.Text = num2str(pre_ictal.false_positive);
            app.PFN.Text = num2str(pre_ictal.false_negative);
            
            app.DTP.Text = num2str(ictal.true_positive);
            app.DTN.Text = num2str(ictal.true_negative);
            app.DFP.Text = num2str(ictal.false_positive);
            app.DFN.Text = num2str(ictal.false_negative);
            
            app.PSensitivity.Text = num2str(pre_ictal.true_positive/(pre_ictal.true_positive + pre_ictal.false_negative));
            app.PSpecificity.Text = num2str(pre_ictal.true_negative/(pre_ictal.true_negative + pre_ictal.false_positive));
                
            app.DSensitivity.Text = num2str(ictal.true_positive/(ictal.true_positive + ictal.false_negative));
            app.DSpecificity.Text = num2str(ictal.true_negative/(ictal.true_negative + ictal.false_positive));
        end

        % Button pushed function: TrainCNNnetworkButton
        function TrainCNNnetworkButtonPushed(app, event)
            [P_train, T_train, P_test, T_test] = loadDataset(app.patient_dataset, app.balance);
            net = train_NN('CNN', P_train, T_train, app.weight_error);
            [~, pre_ictal, ictal] = test_NN('CNN', net, P_test, T_test, app.group);
            app.PTP.Text = num2str(pre_ictal.true_positive);
            app.PTN.Text = num2str(pre_ictal.true_negative);
            app.PFP.Text = num2str(pre_ictal.false_positive);
            app.PFN.Text = num2str(pre_ictal.false_negative);
            
            app.DTP.Text = num2str(ictal.true_positive);
            app.DTN.Text = num2str(ictal.true_negative);
            app.DFP.Text = num2str(ictal.false_positive);
            app.DFN.Text = num2str(ictal.false_negative);
            
            app.PSensitivity.Text = num2str(pre_ictal.true_positive/(pre_ictal.true_positive + pre_ictal.false_negative));
            app.PSpecificity.Text = num2str(pre_ictal.true_negative/(pre_ictal.true_negative + pre_ictal.false_positive));
                
            app.DSensitivity.Text = num2str(ictal.true_positive/(ictal.true_positive + ictal.false_negative));
            app.DSpecificity.Text = num2str(ictal.true_negative/(ictal.true_negative + ictal.false_positive));
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            if app.features2 <= app.features1
                [P_train, T_train, P_test, T_test] = loadDataset(app.patient_dataset, app.balance);
                net = train_NN('autoencoder', P_train, T_train, app.weight_error, app.features1, app.features2);
                [~, pre_ictal, ictal] = test_NN('autoencoder', net, P_test, T_test, app.group);
                app.PTP.Text = num2str(pre_ictal.true_positive);
                app.PTN.Text = num2str(pre_ictal.true_negative);
                app.PFP.Text = num2str(pre_ictal.false_positive);
                app.PFN.Text = num2str(pre_ictal.false_negative);
            
                app.DTP.Text = num2str(ictal.true_positive);
                app.DTN.Text = num2str(ictal.true_negative);
                app.DFP.Text = num2str(ictal.false_positive);
                app.DFN.Text = num2str(ictal.false_negative);
                
                app.PSensitivity.Text = num2str(pre_ictal.true_positive/(pre_ictal.true_positive + pre_ictal.false_negative));
                app.PSpecificity.Text = num2str(pre_ictal.true_negative/(pre_ictal.true_negative + pre_ictal.false_positive));
                
                app.DSensitivity.Text = num2str(ictal.true_positive/(ictal.true_positive + ictal.false_negative));
                app.DSpecificity.Text = num2str(ictal.true_negative/(ictal.true_negative + ictal.false_positive));
            else
                errordlg('Number of features in the first encoder cannot be lower than in the second encoder')
            end
        end

        % Value changed function: FirstencoderfeaturesDropDown
        function FirstencoderfeaturesDropDownValueChanged(app, event)
            value = app.FirstencoderfeaturesDropDown.Value;
            if strcmp(value, '25')
                app.features1 = 25;
            elseif strcmp(value, '20')
                app.features1 = 20;
            elseif strcmp(value, '15')
                app.features1 = 15;
            end
        end

        % Value changed function: SecondencoderfeaturesDropDown
        function SecondencoderfeaturesDropDownValueChanged(app, event)
            value = app.SecondencoderfeaturesDropDown.Value;
            if strcmp(value, '25')
                app.features2 = 25;
            elseif strcmp(value, '20')
                app.features2 = 20;
            elseif strcmp(value, '15')
                app.features2 = 15;
            elseif strcmp(value, '10')
                app.features2 = 10;
            end
        end

        % Value changed function: ChooseanetworkDropDown
        function ChooseanetworkDropDownValueChanged(app, event)
            value = app.ChooseanetworkDropDown.Value;
            if strcmp(value, 'Multilayer')
                app.trained_network = 'multilayer';
            elseif strcmp(value, 'Multilayer with Delays')
                app.trained_network = 'Multilayer with Delays';
            elseif strcmp(value, 'CNN')
                app.trained_network = 'CNN';
            elseif strcmp(value, 'LSTM')
                app.trained_network = 'LSTM';
            elseif strcmp(value, 'Autoencoder / Softmax')
                app.trained_network = 'autoencoder';
            end
        end

        % Button pushed function: TestNNButton
        function TestNNButtonPushed(app, event)
            if strcmp(app.trained_network, 'multilayer')
                load('multilayer.mat');
            elseif strcmp(app.trained_network, 'Multilayer with Delays')
                load('multilayerdelay.mat');
            elseif strcmp(app.trained_network, 'CNN')
                load('cnn.mat');
            elseif strcmp(app.trained_network, 'LSTM')
                load('lstm.mat');
            elseif strcmp(app.trained_network, 'autoencoder')
                load('autoenc.mat');
            end
                
            [~, ~, P_test, T_test] = loadDataset(app.patient_dataset, app.balance);
            [~, pre_ictal, ictal] = test_NN(app.trained_network, net, P_test, T_test, app.group);
            app.PTP.Text = num2str(pre_ictal.true_positive);
            app.PTN.Text = num2str(pre_ictal.true_negative);
            app.PFP.Text = num2str(pre_ictal.false_positive);
            app.PFN.Text = num2str(pre_ictal.false_negative);
            
            app.DTP.Text = num2str(ictal.true_positive);
            app.DTN.Text = num2str(ictal.true_negative);
            app.DFP.Text = num2str(ictal.false_positive);
            app.DFN.Text = num2str(ictal.false_negative);
                
            app.PSensitivity.Text = num2str(pre_ictal.true_positive/(pre_ictal.true_positive + pre_ictal.false_negative));
            app.PSpecificity.Text = num2str(pre_ictal.true_negative/(pre_ictal.true_negative + pre_ictal.false_positive));
                
            app.DSensitivity.Text = num2str(ictal.true_positive/(ictal.true_positive + ictal.false_negative));
            app.DSpecificity.Text = num2str(ictal.true_negative/(ictal.true_negative + ictal.false_positive));
        end

        % Selection changed function: ClassificationButtonGroup
        function ClassificationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ClassificationButtonGroup.SelectedObject;
            if app.PointButton.Value == 1
                app.group = false;
            elseif app.GroupButton.Value == 1
                app.group = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictionanddetectionofepilepticseizuressetupPanel = uipanel(app.UIFigure);
            app.PredictionanddetectionofepilepticseizuressetupPanel.Title = '                                                                            Prediction and detection of epileptic seizures setup';
            app.LoadPatientDatasetButtonGroup = uibuttongroup(app.PredictionanddetectionofepilepticseizuressetupPanel);
            app.LoadPatientDatasetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LoadPatientDatasetButtonGroupSelectionChanged, true);
            app.LoadPatientDatasetButtonGroup.Title = 'Load Patient Dataset';
            app.Patient54802Button = uiradiobutton(app.LoadPatientDatasetButtonGroup);
            app.Patient54802Button.Text = 'Patient 54802';
            app.Patient54802Button.Value = true;
            app.Patient112502Button = uiradiobutton(app.LoadPatientDatasetButtonGroup);
            app.Patient112502Button.Text = 'Patient 112502';
            app.DatasetBalanceSwitchLabel = uilabel(app.PredictionanddetectionofepilepticseizuressetupPanel);
            app.DatasetBalanceSwitchLabel.Text = 'Dataset Balance';
            app.DatasetBalanceSwitch = uiswitch(app.PredictionanddetectionofepilepticseizuressetupPanel, 'slider');
            app.DatasetBalanceSwitch.ValueChangedFcn = createCallbackFcn(app, @DatasetBalanceSwitchValueChanged, true);
            app.SpecificationButtonGroup = uibuttongroup(app.PredictionanddetectionofepilepticseizuressetupPanel);
            app.SpecificationButtonGroup.Title = 'Specification';
            app.PredictionButton = uiradiobutton(app.SpecificationButtonGroup);
            app.PredictionButton.Text = 'Prediction';
            app.PredictionButton.Value = true;
            app.DetectionButton = uiradiobutton(app.SpecificationButtonGroup);
            app.DetectionButton.Text = 'Detection';
            app.NoneButton = uiradiobutton(app.SpecificationButtonGroup);
            app.NoneButton.Text = 'None';
            app.TrainMultilayerNNButton = uibutton(app.PredictionanddetectionofepilepticseizuressetupPanel, 'push');
            app.TrainMultilayerNNButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMultilayerNNButtonPushed, true);
            app.TrainMultilayerNNButton.Text = 'Train Multilayer NN';
            app.TrainNetworkwithDelaysButton = uibutton(app.PredictionanddetectionofepilepticseizuressetupPanel, 'push');
            app.TrainNetworkwithDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkwithDelaysButtonPushed, true);
            app.TrainNetworkwithDelaysButton.Text = 'Train Network with Delays';
            app.TrainLSTMnetworkButton = uibutton(app.PredictionanddetectionofepilepticseizuressetupPanel, 'push');
            app.TrainLSTMnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainLSTMnetworkButtonPushed, true);
            app.TrainLSTMnetworkButton.Text = 'Train LSTM network';
            app.TrainCNNnetworkButton = uibutton(app.PredictionanddetectionofepilepticseizuressetupPanel, 'push');
            app.TrainCNNnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainCNNnetworkButtonPushed, true);
            app.TrainCNNnetworkButton.Text = 'Train CNN network';
            app.ClassificationButtonGroup = uibuttongroup(app.PredictionanddetectionofepilepticseizuressetupPanel);
            app.ClassificationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassificationButtonGroupSelectionChanged, true);
            app.ClassificationButtonGroup.Title = 'Classification';
            app.PointButton = uiradiobutton(app.ClassificationButtonGroup);
            app.PointButton.Text = 'Point';
            app.PointButton.Value = true;
            app.GroupButton = uiradiobutton(app.ClassificationButtonGroup);
            app.GroupButton.Text = 'Group';
            app.NetworkDetectionPredictionoutputsPanel = uipanel(app.UIFigure);
            app.NetworkDetectionPredictionoutputsPanel.Title = '                                          Network Detection / Prediction outputs';
            app.PreIctalPredictionPanel = uipanel(app.NetworkDetectionPredictionoutputsPanel);
            app.PreIctalPredictionPanel.Title = '             Pre-Ictal (Prediction)';
            app.ResultsPanel = uipanel(app.PreIctalPredictionPanel);
            app.ResultsPanel.Title = '                     Results';
            app.TruePositivesLabel = uilabel(app.ResultsPanel);
            app.TruePositivesLabel.Text = 'True Positives:';
            app.TrueNegativesLabel = uilabel(app.ResultsPanel);
            app.TrueNegativesLabel.Text = 'True Negatives:';
            app.FalsePositivesLabel = uilabel(app.ResultsPanel);
            app.FalsePositivesLabel.Text = 'False Positives:';
            app.FalseNegativesLabel = uilabel(app.ResultsPanel);
            app.FalseNegativesLabel.Text = 'False Negatives:';
            app.PTP = uilabel(app.ResultsPanel);
            app.PTP.Text = '0';
            app.PTN = uilabel(app.ResultsPanel);
            app.PTN.Text = '0';
            app.PFP = uilabel(app.ResultsPanel);
            app.PFP.Text = '0';
            app.PFN = uilabel(app.ResultsPanel);
            app.PFN.Text = '0';
            app.SensitivitySpecificityPanel = uipanel(app.PreIctalPredictionPanel);
            app.SensitivitySpecificityPanel.Title = '          Sensitivity / Specificity';
            app.SensitivityLabel = uilabel(app.SensitivitySpecificityPanel);
            app.SensitivityLabel.Text = 'Sensitivity:';
            app.SpecificityLabel = uilabel(app.SensitivitySpecificityPanel);
            app.SpecificityLabel.Text = 'Specificity:';
            app.PSensitivity = uilabel(app.SensitivitySpecificityPanel);
            app.PSensitivity.Text = '0';
            app.PSpecificity = uilabel(app.SensitivitySpecificityPanel);
            app.PSpecificity.Text = '0';
            app.IctalDetectionPanel = uipanel(app.NetworkDetectionPredictionoutputsPanel);
            app.IctalDetectionPanel.Title = '                     Ictal (Detection)';
            app.ResultsPanel_2 = uipanel(app.IctalDetectionPanel);
            app.ResultsPanel_2.Title = '                        Results';
            app.TruePositivesLabel_3 = uilabel(app.ResultsPanel_2);
            app.TruePositivesLabel_3.Text = 'True Positives:';
            app.TrueNegativesLabel_3 = uilabel(app.ResultsPanel_2);
            app.TrueNegativesLabel_3.Text = 'True Negatives:';
            app.FalsePositivesLabel_3 = uilabel(app.ResultsPanel_2);
            app.FalsePositivesLabel_3.Text = 'False Positives:';
            app.FalseNegativesLabel_3 = uilabel(app.ResultsPanel_2);
            app.FalseNegativesLabel_3.Text = 'False Negatives:';
            app.DTP = uilabel(app.ResultsPanel_2);
            app.DTP.Text = '0';
            app.DTN = uilabel(app.ResultsPanel_2);
            app.DTN.Text = '0';
            app.DFP = uilabel(app.ResultsPanel_2);
            app.DFP.Text = '0';
            app.DFN = uilabel(app.ResultsPanel_2);
            app.DFN.Text = '0';
            app.SensitivitySpecificityPanel_2 = uipanel(app.IctalDetectionPanel);
            app.SensitivitySpecificityPanel_2.Title = '           Sensitivity / Specificity';
            app.SensitivityLabel_2 = uilabel(app.SensitivitySpecificityPanel_2);
            app.SensitivityLabel_2.Text = 'Sensitivity:';
            app.SpecificityLabel_2 = uilabel(app.SensitivitySpecificityPanel_2);
            app.SpecificityLabel_2.Text = 'Specificity:';
            app.DSensitivity = uilabel(app.SensitivitySpecificityPanel_2);
            app.DSensitivity.Text = '0';
            app.DSpecificity = uilabel(app.SensitivitySpecificityPanel_2);
            app.DSpecificity.Text = '0';
            app.TrainasofrmaxlayerwithautoencondersPanel = uipanel(app.UIFigure);
            app.TrainasofrmaxlayerwithautoencondersPanel.Title = '      Train a sofrmax layer with auto enconders';
            app.TrainButton = uibutton(app.TrainasofrmaxlayerwithautoencondersPanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.FirstencoderfeaturesDropDownLabel = uilabel(app.TrainasofrmaxlayerwithautoencondersPanel);
            app.FirstencoderfeaturesDropDownLabel.Text = 'First encoder features     ';
            app.FirstencoderfeaturesDropDown = uidropdown(app.TrainasofrmaxlayerwithautoencondersPanel);
            app.FirstencoderfeaturesDropDown.Items = {'25', '20', '15'};
            app.FirstencoderfeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstencoderfeaturesDropDownValueChanged, true);
            app.FirstencoderfeaturesDropDown.Value = '25';
            app.SecondencoderfeaturesDropDownLabel = uilabel(app.TrainasofrmaxlayerwithautoencondersPanel);
            app.SecondencoderfeaturesDropDownLabel.Text = 'Second encoder features';
            app.SecondencoderfeaturesDropDown = uidropdown(app.TrainasofrmaxlayerwithautoencondersPanel);
            app.SecondencoderfeaturesDropDown.Items = {'25', '20', '15', '10'};
            app.SecondencoderfeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondencoderfeaturesDropDownValueChanged, true);
            app.SecondencoderfeaturesDropDown.Value = '25';
            app.SelectinthesetupDatasetBalanceaspreferredLabel = uilabel(app.TrainasofrmaxlayerwithautoencondersPanel);
            app.SelectinthesetupDatasetBalanceaspreferredLabel.Text = 'Select in the setup Dataset Balance as preferred';
            app.TestanalreadytrainednetworkPanel = uipanel(app.UIFigure);
            app.TestanalreadytrainednetworkPanel.Title = '                 Test an already trained network';
            app.ChooseanetworkDropDownLabel = uilabel(app.TestanalreadytrainednetworkPanel);
            app.ChooseanetworkDropDownLabel.Text = 'Choose a network';
            app.ChooseanetworkDropDown = uidropdown(app.TestanalreadytrainednetworkPanel);
            app.ChooseanetworkDropDown.Items = {'Multilayer', 'Multilayer with Delays', 'CNN', 'LSTM', 'Autoencoder / Softmax'};
            app.ChooseanetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseanetworkDropDownValueChanged, true);
            app.ChooseanetworkDropDown.Value = 'Multilayer';
            app.AnynetworktrainedinthisapplicationwillLabel = uilabel(app.TestanalreadytrainednetworkPanel);
            app.AnynetworktrainedinthisapplicationwillLabel.Text = {'Initial trained networks are for Patient 54802'; 'Any network trained in this application will'; 'automatically replace any previously'; 'saved network'};
            app.TestNNButton = uibutton(app.TestanalreadytrainednetworkPanel, 'push');
            app.TestNNButton.ButtonPushedFcn = createCallbackFcn(app, @TestNNButtonPushed, true);
            app.TestNNButton.Text = 'Test NN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = epileptic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end